classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FilePath2EditField       matlab.ui.control.EditField
        FilePath2EditFieldLabel  matlab.ui.control.Label
        FilePathEditField        matlab.ui.control.EditField
        FilePathEditFieldLabel   matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        bLabel                   matlab.ui.control.Label
        aEditField               matlab.ui.control.NumericEditField
        aLabel                   matlab.ui.control.Label
        ImportButton2            matlab.ui.control.Button
        ImportButton             matlab.ui.control.Button
        ModeDropDown             matlab.ui.control.DropDown
        ModeDropDownLabel        matlab.ui.control.Label
        OptionDropDown           matlab.ui.control.DropDown
        OptionDropDownLabel      matlab.ui.control.Label
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function main_logic(app)
            if strcmp(app.FilePathEditField.Value, '')
                return
            end
            
            value = app.OptionDropDown.Value;
            image = imread(app.FilePathEditField.Value);
            imshow(image, 'Parent', app.UIAxes);
            switch value
                case "Histogram"
                    bar(0:255, histo(image), 'Parent', app.UIAxes_2);
                case "Image Enhancement"
                    value2 = app.ModeDropDown.Value;
                    switch value2
                        case "Negative"
                            imshow(negative(image), 'Parent', app.UIAxes_5);
                            bar(0:255, histo(negative(image)), 'Parent', app.UIAxes_6);
                        case "Brightening"
                            a = app.aEditField.Value;
                            b = app.bEditField.Value;
                            imshow(brightening(image, b, a), 'Parent', app.UIAxes_5);
                            bar(0:255, histo(brightening(image, b, a)), 'Parent', app.UIAxes_6);
                        case "Log Transform"
                            c = app.aEditField.Value;
                            imshow(logtrans(image, c), 'Parent', app.UIAxes_5);
                            bar(0:255, histo(im2uint8(logtrans(image, c))), 'Parent', app.UIAxes_6);
                        case "Exp Transform"
                            c = app.aEditField.Value;
                            gamma = app.bEditField.Value;
                            imshow(powtrans(image, c, gamma), 'Parent', app.UIAxes_5);
                            bar(0:255, histo(im2uint8(powtrans(image, c, gamma))), 'Parent', app.UIAxes_6);
                        case "Contrast Stretch"
                            imshow(contraststretching(image), 'Parent', app.UIAxes_5);
                            bar(0:255, histo(contraststretching(image)), 'Parent', app.UIAxes_6);
                    end
                case "Histogram Equalization"
                    imshow(histequal(image), 'Parent', app.UIAxes_5);
                    bar(0:255, histo(histequal(image)), 'Parent', app.UIAxes_6);
                case "Histogram Matching"
                    if strcmp(app.FilePath2EditField.Value, '')
                        return
                    end
                    image2 = imread(app.FilePath2EditField.Value);
                    imshow(image2, 'Parent', app.UIAxes_5);
                    bar(0:255, histo(image2), 'Parent', app.UIAxes_6);
                    imshow(histmatch(image, image2), 'Parent', app.UIAxes_3);
                    bar(0:255, histo(histmatch(image, image2)), 'Parent', app.UIAxes_4);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [path, canceled] = imgetfile();
            if canceled
                figure(app.UIFigure);
                return
            end
            app.FilePathEditField.Value = path;
            % image = imread(path);
            % imshow(image, 'Parent', app.UIAxes);
            figure(app.UIFigure);

            main_logic(app);
        end

        % Value changed function: OptionDropDown
        function OptionDropDownValueChanged(app, event)
            value = app.OptionDropDown.Value;

            % restore to default
            app.UIAxes_3.Visible = "off"; set(app.UIAxes_3.Children, 'Visible', 'off');
            app.UIAxes_4.Visible = "off"; set(app.UIAxes_4.Children, 'Visible', 'off');
            app.UIAxes_5.Visible = "off"; set(app.UIAxes_5.Children, 'Visible', 'off');
            app.UIAxes_6.Visible = "off"; set(app.UIAxes_6.Children, 'Visible', 'off');
            app.ModeDropDown.Visible = "off";
            app.FilePath2EditField.Visible = "off";
            app.FilePath2EditFieldLabel.Visible = "off";
            app.ImportButton2.Visible = "off";
            app.aEditField.Visible = "off";
            app.bEditField.Visible = "off";
            app.aLabel.Visible = "off";
            app.bLabel.Visible = "off";

            switch value
                case 'Image Enhancement'
                    app.UIAxes_5.Visible = "on"; set(app.UIAxes_5.Children, 'Visible', 'off');
                    app.UIAxes_6.Visible = "on"; set(app.UIAxes_6.Children, 'Visible', 'off');
                    app.ModeDropDown.Visible = "on";
                case 'Histogram Equalization'
                    app.UIAxes_5.Visible = "on"; set(app.UIAxes_5.Children, 'Visible', 'off');
                    app.UIAxes_6.Visible = "on"; set(app.UIAxes_6.Children, 'Visible', 'off');
                case 'Histogram Matching'
                    app.UIAxes_3.Visible = "on"; set(app.UIAxes_3.Children, 'Visible', 'off');
                    app.UIAxes_4.Visible = "on"; set(app.UIAxes_4.Children, 'Visible', 'off');
                    app.UIAxes_5.Visible = "on"; set(app.UIAxes_5.Children, 'Visible', 'off');
                    app.UIAxes_6.Visible = "on"; set(app.UIAxes_6.Children, 'Visible', 'off');
                    app.FilePath2EditField.Visible = "on";
                    app.FilePath2EditFieldLabel.Visible = "on";
                    app.ImportButton2.Visible = "on";
            end

            main_logic(app);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;

            % restore to default
            app.aEditField.Visible = "off";
            app.bEditField.Visible = "off";
            app.aLabel.Visible = "off"; 
            app.bLabel.Visible = "off";
            switch value
                case 'Brightening'
                    app.aEditField.Visible = "on";
                    app.bEditField.Visible = "on";
                    app.aLabel.Visible = "on"; 
                    app.bLabel.Visible = "on";
                    app.aLabel.Text = "a"; 
                    app.bLabel.Text = "b";
                case 'Log Transform'
                    app.aEditField.Visible = "on";
                    app.aLabel.Visible = "on"; 
                    app.aLabel.Text = "c"; 
                case 'Exp Transform'
                    app.aEditField.Visible = "on";
                    app.bEditField.Visible = "on";
                    app.aLabel.Visible = "on";
                    app.bLabel.Visible = "on";
                    app.aLabel.Text = "c";
                    app.bLabel.Text = "γ";
            end

            main_logic(app);
        end

        % Button pushed function: ImportButton2
        function ImportButton2Pushed(app, event)
            [path, canceled] = imgetfile();
            if canceled
                figure(app.UIFigure);
                return
            end
            app.FilePath2EditField.Value = path;
            % image = imread(path);
            % imshow(image, 'Parent', app.UIAxes_5);
            % bar(0:255, histo(image), 'Parent', app.UIAxes_6);
            figure(app.UIFigure);

            main_logic(app);
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            if ~isempty(value)
                main_logic(app);
            end
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            if ~isempty(value)
                main_logic(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.UIFigure);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.XTickLabel = '';
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.Visible = 'off';
            app.OptionDropDownLabel = uilabel(app.UIFigure);
            app.OptionDropDownLabel.Text = 'Option';
            app.OptionDropDown = uidropdown(app.UIFigure);
            app.OptionDropDown.Items = {'Histogram', 'Image Enhancement', 'Histogram Equalization', 'Histogram Matching'};
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionDropDownValueChanged, true);
            app.OptionDropDown.Value = 'Histogram';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Enable = 'off';
            app.ModeDropDownLabel.Visible = 'off';
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Negative', 'Brightening', 'Log Transform', 'Exp Transform', 'Contrast Stretch'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Visible = 'off';
            app.ModeDropDown.Value = 'Negative';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ImportButton2 = uibutton(app.UIFigure, 'push');
            app.ImportButton2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton2Pushed, true);
            app.ImportButton2.Visible = 'off';
            app.ImportButton2.Text = 'Import';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Visible = 'off';
            app.aLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Visible = 'off';
            app.aEditField.Value = 1;
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Visible = 'off';
            app.bLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Visible = 'off';
            app.bEditField.Value = 1;
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePath2EditFieldLabel = uilabel(app.UIFigure);
            app.FilePath2EditFieldLabel.Visible = 'off';
            app.FilePath2EditFieldLabel.Text = 'File Path 2';
            app.FilePath2EditField = uieditfield(app.UIFigure, 'text');
            app.FilePath2EditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end